classdef inputApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Settings                        matlab.ui.container.Panel
        BATCH                           matlab.ui.control.CheckBox
        IR                              matlab.ui.control.CheckBox
        SaveandExitButton               matlab.ui.control.Button
        MBF                             matlab.ui.control.NumericEditField
        MaximumBadFramesEditFieldLabel  matlab.ui.control.Label
        LV                              matlab.ui.control.CheckBox
        RLED                            matlab.ui.control.NumericEditField
        RightLedPositionEditFieldLabel  matlab.ui.control.Label
        LLED                            matlab.ui.control.NumericEditField
        LeftLedPositionEditFieldLabel   matlab.ui.control.Label
        FTA                             matlab.ui.control.NumericEditField
        FramestoAverageEditFieldLabel   matlab.ui.control.Label
        CT                              matlab.ui.control.NumericEditField
        ConfidenceThresholdEditFieldLabel  matlab.ui.control.Label
        ROI                             matlab.ui.container.TabGroup
        Bank1ROITab                     matlab.ui.container.Tab
        P1X4                            matlab.ui.control.NumericEditField
        X4Label                         matlab.ui.control.Label
        P1Y4                            matlab.ui.control.NumericEditField
        Y4Label                         matlab.ui.control.Label
        P1X3                            matlab.ui.control.NumericEditField
        X3Label                         matlab.ui.control.Label
        P1Y3                            matlab.ui.control.NumericEditField
        Y3Label                         matlab.ui.control.Label
        Bank1ROICoordinatesLabel        matlab.ui.control.Label
        P1X1                            matlab.ui.control.NumericEditField
        X1EditFieldLabel                matlab.ui.control.Label
        P1Y1                            matlab.ui.control.NumericEditField
        Y1EditFieldLabel                matlab.ui.control.Label
        P1Y2                            matlab.ui.control.NumericEditField
        Y2EditFieldLabel                matlab.ui.control.Label
        P1X2                            matlab.ui.control.NumericEditField
        X2EditFieldLabel                matlab.ui.control.Label
        Bank2ROITab                     matlab.ui.container.Tab
        P2X4                            matlab.ui.control.NumericEditField
        X4Label_2                       matlab.ui.control.Label
        P2Y4                            matlab.ui.control.NumericEditField
        Y4Label_2                       matlab.ui.control.Label
        P2X3                            matlab.ui.control.NumericEditField
        X3Label_2                       matlab.ui.control.Label
        P2Y3                            matlab.ui.control.NumericEditField
        Y3Label_2                       matlab.ui.control.Label
        Bank2ROICoordinatesLabel        matlab.ui.control.Label
        P2X1                            matlab.ui.control.NumericEditField
        X1EditFieldLabel_2              matlab.ui.control.Label
        P2Y1                            matlab.ui.control.NumericEditField
        Y1EditFieldLabel_2              matlab.ui.control.Label
        P2Y2                            matlab.ui.control.NumericEditField
        Y2EditFieldLabel_2              matlab.ui.control.Label
        P2X2                            matlab.ui.control.NumericEditField
        X2EditFieldLabel_2              matlab.ui.control.Label
        Bank1PortsTab                   matlab.ui.container.Tab
        Port3Label                      matlab.ui.control.Label
        Port2Label                      matlab.ui.control.Label
        Port1Label                      matlab.ui.control.Label
        B1P3Y                           matlab.ui.control.NumericEditField
        YEditField_3Label               matlab.ui.control.Label
        B1P3X                           matlab.ui.control.NumericEditField
        XEditField_2Label               matlab.ui.control.Label
        Bank1PortsLabel                 matlab.ui.control.Label
        B1P1X                           matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        B1P1Y                           matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        B1P2Y                           matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        B1P2X                           matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
        Bank2PortsTab                   matlab.ui.container.Tab
        Port3Label_3                    matlab.ui.control.Label
        Port2Label_3                    matlab.ui.control.Label
        Port1Label_3                    matlab.ui.control.Label
        B2P3Y                           matlab.ui.control.NumericEditField
        YEditField_6Label               matlab.ui.control.Label
        B2P3X                           matlab.ui.control.NumericEditField
        XEditField_4Label               matlab.ui.control.Label
        Bank2PortsLabel                 matlab.ui.control.Label
        B2P1X                           matlab.ui.control.NumericEditField
        XEditField_3Label               matlab.ui.control.Label
        B2P1Y                           matlab.ui.control.NumericEditField
        YEditField_5Label               matlab.ui.control.Label
        B2P2Y                           matlab.ui.control.NumericEditField
        YEditField_4Label               matlab.ui.control.Label
        B2P2X                           matlab.ui.control.NumericEditField
        XLabel_2                        matlab.ui.control.Label
        FinishedTab                     matlab.ui.container.Tab
        PressthebuttontoclosetheinputpromptwhenfinishedLabel  matlab.ui.control.Label
        CLOSEButton                     matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function results = func(app)

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CLOSEButton
        function CLOSEButtonPushed(app, event)
            
             port1ROIInput = zeros(4,2);            
             port1ROIInput(1,1) = app.P1X1.Value;
             port1ROIInput(2,1) = app.P1X2.Value;
             port1ROIInput(3,1) = app.P1X3.Value;
             port1ROIInput(4,1) = app.P1X4.Value;
             port1ROIInput(1,2) = app.P1Y1.Value;
             port1ROIInput(2,2) = app.P1Y2.Value;
             port1ROIInput(3,2) = app.P1Y3.Value;
             port1ROIInput(4,2) = app.P1Y4.Value;     
             assignin('base', 'odorRROI', port1ROIInput);
             
             port2ROIInput = zeros(4,2);            
             port2ROIInput(1,1) = app.P2X1.Value;
             port2ROIInput(2,1) = app.P2X2.Value;
             port2ROIInput(3,1) = app.P2X3.Value;
             port2ROIInput(4,1) = app.P2X4.Value;
             port2ROIInput(1,2) = app.P2Y1.Value;
             port2ROIInput(2,2) = app.P2Y2.Value;
             port2ROIInput(3,2) = app.P2Y3.Value;
             port2ROIInput(4,2) = app.P2Y4.Value;     
             assignin('base', 'odorLROI', port2ROIInput);
             
%              odorROIInput = zeros(4,2);            
%              odorROI(1,1) = app.OX1.Value;
%              odorROI(2,1) = app.OX2.Value;
%              odorROI(3,1) = app.OX3.Value;
%              odorROI(4,1) = app.OX4.Value;
%              odorROI(1,2) = app.OY1.Value;
%              odorROI(2,2) = app.OY2.Value;
%              odorROI(3,2) = app.OY3.Value;
%              odorROI(4,2) = app.OY4.Value;     
%              assignin('base', 'odorROI', odorROI);             

             bank1Locs = zeros(3,2);            
             bank1Locs(1,1) = app.B1P1X.Value;
             bank1Locs(2,1) = app.B1P2X.Value;
             bank1Locs(3,1) = app.B1P3X.Value;
             bank1Locs(1,2) = app.B1P1Y.Value;
             bank1Locs(2,2) = app.B1P2Y.Value;
             bank1Locs(3,2) = app.B1P3Y.Value;
             assignin('base', 'bank1Locs', bank1Locs);
             
             bank2Locs = zeros(3,2);            
             bank2Locs(1,1) = app.B2P1X.Value;
             bank2Locs(2,1) = app.B2P2X.Value;
             bank2Locs(3,1) = app.B2P3X.Value;
             bank2Locs(1,2) = app.B2P1Y.Value;
             bank2Locs(2,2) = app.B2P2Y.Value;
             bank2Locs(3,2) = app.B2P3Y.Value;
             assignin('base', 'bank2Locs', bank2Locs);
             
                close(app.UIFigure);
        end

        % Value changed function: P1X1
        function P1X1ValueChanged(app, event)
            value = app.P1X1.Value;
            setappdata(app.UIFigure, 'P1X1', value);
        end

        % Button pushed function: SaveandExitButton
        function SaveandExitButtonPushed(app, event)
             assignin('base', 'confidenceThreshold', app.CT.Value);
             assignin('base', 'max_bad_frames', app.MBF.Value);
             assignin('base', 'frames_to_average', app.FTA.Value);
             assignin('base', 'ledLeftX', app.LLED.Value);
             assignin('base', 'ledRightX', app.RLED.Value);
             assignin('base','createVideo', app.LV.Value);
             assignin('base','inputROI', app.IR.Value);
             assignin('base','batchProcess', app.BATCH.Value);
            close(app.UIFigure);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ROI = uitabgroup(app.UIFigure);
            app.ROI.Visible = 'off';
            app.Bank1ROITab = uitab(app.ROI);
            app.Bank1ROITab.Title = 'Bank1 ROI';
            app.X2EditFieldLabel = uilabel(app.Bank1ROITab);
            app.X2EditFieldLabel.Text = 'X2';
            app.P1X2 = uieditfield(app.Bank1ROITab, 'numeric');
            app.Y2EditFieldLabel = uilabel(app.Bank1ROITab);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.P1Y2 = uieditfield(app.Bank1ROITab, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.Bank1ROITab);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.P1Y1 = uieditfield(app.Bank1ROITab, 'numeric');
            app.X1EditFieldLabel = uilabel(app.Bank1ROITab);
            app.X1EditFieldLabel.Text = 'X1';
            app.P1X1 = uieditfield(app.Bank1ROITab, 'numeric');
            app.P1X1.ValueChangedFcn = createCallbackFcn(app, @P1X1ValueChanged, true);
            app.Bank1ROICoordinatesLabel = uilabel(app.Bank1ROITab);
            app.Bank1ROICoordinatesLabel.Text = 'Bank 1 ROI Coordinates';
            app.Y3Label = uilabel(app.Bank1ROITab);
            app.Y3Label.Text = 'Y3';
            app.P1Y3 = uieditfield(app.Bank1ROITab, 'numeric');
            app.X3Label = uilabel(app.Bank1ROITab);
            app.X3Label.Text = 'X3';
            app.P1X3 = uieditfield(app.Bank1ROITab, 'numeric');
            app.Y4Label = uilabel(app.Bank1ROITab);
            app.Y4Label.Text = 'Y4';
            app.P1Y4 = uieditfield(app.Bank1ROITab, 'numeric');
            app.X4Label = uilabel(app.Bank1ROITab);
            app.X4Label.Text = 'X4';
            app.P1X4 = uieditfield(app.Bank1ROITab, 'numeric');
            app.Bank2ROITab = uitab(app.ROI);
            app.Bank2ROITab.Title = 'Bank 2 ROI';
            app.X2EditFieldLabel_2 = uilabel(app.Bank2ROITab);
            app.X2EditFieldLabel_2.Text = 'X2';
            app.P2X2 = uieditfield(app.Bank2ROITab, 'numeric');
            app.Y2EditFieldLabel_2 = uilabel(app.Bank2ROITab);
            app.Y2EditFieldLabel_2.Text = 'Y2';
            app.P2Y2 = uieditfield(app.Bank2ROITab, 'numeric');
            app.Y1EditFieldLabel_2 = uilabel(app.Bank2ROITab);
            app.Y1EditFieldLabel_2.Text = 'Y1';
            app.P2Y1 = uieditfield(app.Bank2ROITab, 'numeric');
            app.X1EditFieldLabel_2 = uilabel(app.Bank2ROITab);
            app.X1EditFieldLabel_2.Text = 'X1';
            app.P2X1 = uieditfield(app.Bank2ROITab, 'numeric');
            app.Bank2ROICoordinatesLabel = uilabel(app.Bank2ROITab);
            app.Bank2ROICoordinatesLabel.Text = 'Bank 2 ROI Coordinates';
            app.Y3Label_2 = uilabel(app.Bank2ROITab);
            app.Y3Label_2.Text = 'Y3';
            app.P2Y3 = uieditfield(app.Bank2ROITab, 'numeric');
            app.X3Label_2 = uilabel(app.Bank2ROITab);
            app.X3Label_2.Text = 'X3';
            app.P2X3 = uieditfield(app.Bank2ROITab, 'numeric');
            app.Y4Label_2 = uilabel(app.Bank2ROITab);
            app.Y4Label_2.Text = 'Y4';
            app.P2Y4 = uieditfield(app.Bank2ROITab, 'numeric');
            app.X4Label_2 = uilabel(app.Bank2ROITab);
            app.X4Label_2.Text = 'X4';
            app.P2X4 = uieditfield(app.Bank2ROITab, 'numeric');
            app.Bank1PortsTab = uitab(app.ROI);
            app.Bank1PortsTab.Title = 'Bank 1 Ports';
            app.XLabel = uilabel(app.Bank1PortsTab);
            app.XLabel.Text = 'X';
            app.B1P2X = uieditfield(app.Bank1PortsTab, 'numeric');
            app.YEditField_2Label = uilabel(app.Bank1PortsTab);
            app.YEditField_2Label.Text = 'Y';
            app.B1P2Y = uieditfield(app.Bank1PortsTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.Bank1PortsTab);
            app.YEditFieldLabel.Text = 'Y';
            app.B1P1Y = uieditfield(app.Bank1PortsTab, 'numeric');
            app.XEditFieldLabel = uilabel(app.Bank1PortsTab);
            app.XEditFieldLabel.Text = 'X';
            app.B1P1X = uieditfield(app.Bank1PortsTab, 'numeric');
            app.Bank1PortsLabel = uilabel(app.Bank1PortsTab);
            app.Bank1PortsLabel.Text = 'Bank 1 Ports';
            app.XEditField_2Label = uilabel(app.Bank1PortsTab);
            app.XEditField_2Label.Text = 'X';
            app.B1P3X = uieditfield(app.Bank1PortsTab, 'numeric');
            app.YEditField_3Label = uilabel(app.Bank1PortsTab);
            app.YEditField_3Label.Text = 'Y';
            app.B1P3Y = uieditfield(app.Bank1PortsTab, 'numeric');
            app.Port1Label = uilabel(app.Bank1PortsTab);
            app.Port1Label.Text = 'Port 1';
            app.Port2Label = uilabel(app.Bank1PortsTab);
            app.Port2Label.Text = 'Port 2';
            app.Port3Label = uilabel(app.Bank1PortsTab);
            app.Port3Label.Text = 'Port 3';
            app.Bank2PortsTab = uitab(app.ROI);
            app.Bank2PortsTab.Title = 'Bank 2 Ports';
            app.XLabel_2 = uilabel(app.Bank2PortsTab);
            app.XLabel_2.Text = 'X';
            app.B2P2X = uieditfield(app.Bank2PortsTab, 'numeric');
            app.YEditField_4Label = uilabel(app.Bank2PortsTab);
            app.YEditField_4Label.Text = 'Y';
            app.B2P2Y = uieditfield(app.Bank2PortsTab, 'numeric');
            app.YEditField_5Label = uilabel(app.Bank2PortsTab);
            app.YEditField_5Label.Text = 'Y';
            app.B2P1Y = uieditfield(app.Bank2PortsTab, 'numeric');
            app.XEditField_3Label = uilabel(app.Bank2PortsTab);
            app.XEditField_3Label.Text = 'X';
            app.B2P1X = uieditfield(app.Bank2PortsTab, 'numeric');
            app.Bank2PortsLabel = uilabel(app.Bank2PortsTab);
            app.Bank2PortsLabel.Text = 'Bank 2 Ports';
            app.XEditField_4Label = uilabel(app.Bank2PortsTab);
            app.XEditField_4Label.Text = 'X';
            app.B2P3X = uieditfield(app.Bank2PortsTab, 'numeric');
            app.YEditField_6Label = uilabel(app.Bank2PortsTab);
            app.YEditField_6Label.Text = 'Y';
            app.B2P3Y = uieditfield(app.Bank2PortsTab, 'numeric');
            app.Port1Label_3 = uilabel(app.Bank2PortsTab);
            app.Port1Label_3.Text = 'Port 1';
            app.Port2Label_3 = uilabel(app.Bank2PortsTab);
            app.Port2Label_3.Text = 'Port 2';
            app.Port3Label_3 = uilabel(app.Bank2PortsTab);
            app.Port3Label_3.Text = 'Port 3';
            app.FinishedTab = uitab(app.ROI);
            app.FinishedTab.Title = 'Finished';
            app.CLOSEButton = uibutton(app.FinishedTab, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSEButton.Tooltip = {'Click me to close'};
            app.CLOSEButton.Text = 'CLOSE';
            app.PressthebuttontoclosetheinputpromptwhenfinishedLabel = uilabel(app.FinishedTab);
            app.PressthebuttontoclosetheinputpromptwhenfinishedLabel.Text = 'Press the button to close the input prompt when finished!';
            app.Settings = uipanel(app.UIFigure);
            app.Settings.Title = 'Initial Settings';
            app.ConfidenceThresholdEditFieldLabel = uilabel(app.Settings);
            app.ConfidenceThresholdEditFieldLabel.Text = 'Confidence Threshold';
            app.CT = uieditfield(app.Settings, 'numeric');
            app.CT.Limits = [0 1];
            app.CT.Value = 0.9;
            app.FramestoAverageEditFieldLabel = uilabel(app.Settings);
            app.FramestoAverageEditFieldLabel.Text = 'Frames to Average';
            app.FTA = uieditfield(app.Settings, 'numeric');
            app.FTA.Limits = [0 Inf];
            app.FTA.Value = 110;
            app.LeftLedPositionEditFieldLabel = uilabel(app.Settings);
            app.LeftLedPositionEditFieldLabel.Text = 'Left Led Position';
            app.LLED = uieditfield(app.Settings, 'numeric');
            app.LLED.Limits = [0 1920];
            app.LLED.RoundFractionalValues = 'on';
            app.LLED.Value = 300;
            app.RightLedPositionEditFieldLabel = uilabel(app.Settings);
            app.RightLedPositionEditFieldLabel.Text = 'Right Led Position';
            app.RLED = uieditfield(app.Settings, 'numeric');
            app.RLED.Limits = [0 1920];
            app.RLED.RoundFractionalValues = 'on';
            app.RLED.Value = 1700;
            app.LV = uicheckbox(app.Settings);
            app.LV.Text = 'Create Labeled Video';
            app.MaximumBadFramesEditFieldLabel = uilabel(app.Settings);
            app.MaximumBadFramesEditFieldLabel.Text = 'Maximum Bad Frames';
            app.MBF = uieditfield(app.Settings, 'numeric');
            app.MBF.Limits = [0 Inf];
            app.MBF.Value = 5;
            app.SaveandExitButton = uibutton(app.Settings, 'push');
            app.SaveandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandExitButtonPushed, true);
            app.SaveandExitButton.Text = 'Save and Exit';
            app.IR = uicheckbox(app.Settings);
            app.IR.Text = 'Manually Input ROI';
            app.BATCH = uicheckbox(app.Settings);
            app.BATCH.Text = 'Batch Process';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inputApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end